classdef SignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputPanel                  matlab.ui.container.Panel
        FolderPath                  matlab.ui.control.EditField
        BrowseFolder                matlab.ui.control.Button
        FilenameCh1                 matlab.ui.control.EditField
        BrowseCh1                   matlab.ui.control.Button
        FilenameCh2                 matlab.ui.control.EditField
        BrowseCh2                   matlab.ui.control.Button
        Channel2Label               matlab.ui.control.Label
        SetButton                   matlab.ui.control.Button
        Channel1Label               matlab.ui.control.Label
        DirectoryEditFieldLabel     matlab.ui.control.Label
        SignalAnalysisPanel         matlab.ui.container.Panel
        GetSignal                   matlab.ui.control.Button
        SelectROI                   matlab.ui.control.Button
        Overlay                     matlab.ui.control.Button
        StartFrame                  matlab.ui.control.NumericEditField
        EndFrame                    matlab.ui.control.NumericEditField
        EndFrameLabel               matlab.ui.control.Label
        StartFrameLabel             matlab.ui.control.Label
        EnhancedPhaseMatchingPanel  matlab.ui.container.ButtonGroup
        RunROIMatch_3               matlab.ui.control.Button
        RunROIMatch_2               matlab.ui.control.Button
        MeanDistLabel               matlab.ui.control.Label
        Padding                     matlab.ui.control.NumericEditField
        CheckNeighbours             matlab.ui.control.NumericEditField
        SamplingRateLabel           matlab.ui.control.Label
        RunROIMatch                 matlab.ui.control.Button
        OutputPanel                 matlab.ui.container.ButtonGroup
        Session                     matlab.ui.control.RadioButton
        OutputName                  matlab.ui.control.EditField
        OutputNameLabel             matlab.ui.control.Label
        MultiTiff                   matlab.ui.control.RadioButton
        PhaseOme                    matlab.ui.control.RadioButton
        SingleOme                   matlab.ui.control.RadioButton
        SaveButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        pathnames           % structure containing full pathname
        pathnameChMain      % full pathname for main reference channel
        N_channels          % number of channels we have inputted data for
        img_ref             % reference image
        tifInfo             % information about data
        N                   % number of frames in movie
        ssim_score_lst      % list of ssim_scores
        pk_locs             % location of peaks
        N_pks               % number of peaks
        peaks_plot          % plot of peaks values
        cutLength           % number of frames
        roi                 % region of interest
        DialogApp           % Dialog box app
        offset              % offset from reference for correction
        ImagesToSave        % images to save structure
        
    end

    methods (Access = public)     
        function UpdateOffset(app, selected)
            app.offset = selected;
            %msgbox(app.offset);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseCh1
        function BrowseCh1ButtonPushed(app, event)
             app.FilenameCh1.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCh2
        function BrowseCh2ButtonPushed(app, event)
            app.FilenameCh2.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            addpath(genpath('utility'))
            [app.pathnames, app.N_channels, app.pathnameChMain, app.tifInfo, app.img_ref] = initialise( ...
                app.FolderPath.Value, app.FilenameCh1.Value, ...
                app.FilenameCh2.Value, app.FilenameCh3.Value, ...
                app.MainCh.Value, app.Phase.Value);

            figure(1);
            imshow(app.img_ref);
            title('This is our reference image for phase matching.')
        end

        % Button pushed function: Overlay
        function OverlayPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Calculating similarity to reference image. Please wait...');

            [app.ssim_score_lst, app.N] = pre_phase_matching( ...
                app.tifInfo, app.pathnameChMain, app.img_ref, app.NumScales.Value);

            figure(2);
            plot(1:app.N, app.ssim_score_lst);
            title('ssim scores');

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Similarity score calculated. Please proceed to find peaks.')
        end

        % Button pushed function: RunROIMatch
        function RunROIGoodPeaksPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Initiating phase matching, select ROI in figure 1.');

            % Initialisation - cut images into one cardiac cycle + n frames
            [app.cutLength, app.ImagesToSave] = init_output( ...
                app.pk_locs, app.Padding.Value, app.N_channels, app.N_pks);

            % Show image for user to pick region of interest
            app.img_ref = imread(app.pathnameChMain, app.Phase.Value);
            figure(1);
            imshow(app.img_ref);
            title('Please draw a ROI to focus on here.')
            ROI = drawrectangle;
            app.roi = ROI.Position;
            xbounds = ceil(app.roi(1)):floor(app.roi(1)+app.roi(3));
            ybounds = ceil(app.roi(2)):floor(app.roi(2)+app.roi(4));
            n_neighbours = app.CheckNeighbours.Value; % This algorithm is good enough that we don't need to check many neighbours
            
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Performing enhanced phase matching. Please wait...');
            
            % Perform corrections depending on mode
            switch app.EnhancedPhaseMatchingPanel.SelectedObject.Tag
                case 'AutoCorrect'
                    app.ImagesToSave = adv_phase_matching( ...
                        app.N_pks, app.pathnameChMain, xbounds, ybounds, ...
                        app.Phase.Value, app.pk_locs, app.CheckNeighbours.Value, ...
                        app.cutLength, app.N_channels, app.tifInfo, app.ImagesToSave, app.pathnames);

                case 'HybridCorrect'
                    app.offset = 0;
                    for i = 1:app.N_pks-1
                        if i~= 1
                            ssim_vals = auto_correct(app.pathnameChMain, xbounds, ybounds, app.Phase.Value, app.pk_locs(i), n_neighbours);
                            [M_1, I_1] = max(ssim_vals);
                            offset_1 = I_1-n_neighbours-1;
                            ssim_vals(I_1) = 0;
                            [M_2, I_2] = max(ssim_vals);
                            offset_2 = I_2-n_neighbours-1;
                            if (M_1 - M_2)/M_1 < app.Threshold.Value/100 % If close call, get user to select. Add tolerance option???
                                app.DialogApp = ManualCorrect( ...
                                    app, app.pathnameChMain, app.pk_locs(i), ...
                                    offset_1, offset_2);
                                uiwait;
                            else
                                app.offset = offset_1;
                            end
                        end
                        k = 0;
                        for j = -app.cutLength: 1: app.cutLength
                            k = k + 1;
                            frame_idx = app.pk_locs(i)+j+app.offset;
                            for ch = 1:app.N_channels
                                if frame_idx >= 1 && frame_idx <= length(app.tifInfo)
                                    app.ImagesToSave{ch, i, k} = imread(app.pathnames{ch}, frame_idx);
                                else
                                    app.ImagesToSave{ch, i, k} = zeros(app.tifInfo(1).Height, app.tifInfo(1).Width);
                                end
                            end
                        end
                    end
     
            end
            
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            close all;
            msg = msgbox('Phase matching complete. Select save option.');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Saving files');

            outputFolder = app.OutputName.Value;
            outputFileName = app.OutputName.Value;
            folder_w = [app.FolderPath.Value, outputFolder];
            mkdir(folder_w);
            javaaddpath('loci_tools.jar')

            switch app.OutputPanel.SelectedObject.Tag
                case 'MultiTiff'
                    save_multitiff(app.N_pks, app.N_channels, app.cutLength, ...
                        app.ImagesToSave, outputFileName, folder_w)

                case 'PhaseOme'
                    save_phase(app.tifInfo, app.N_pks, app.N_channels, app.cutLength, ...
                        app.ImagesToSave, outputFileName, folder_w, app.Phase.Value);

                case 'SingleOme'
                    save_all(app.tifInfo, app.N_pks, app.N_channels, app.cutLength, ...
                        app.ImagesToSave, outputFileName, folder_w);

                case 'Session'
                    save_settings(app.FolderPath.Value, app.FilenameCh1.Value, app.FilenameCh2.Value, app.FilenameCh3.Value, ...
                        app.MainCh.Value, app.Phase.Value, app.NumScales.Value, app.StartFrame.Value, app.EndFrame.Value, ...
                        app.roi, app.Padding.Value, app.CheckNeighbours.Value, app.OutputName.Value);
            end

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Files saved');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SingleOme = uiradiobutton(app.OutputPanel);
            app.SingleOme.Tag = 'SingleOme';
            app.SingleOme.Text = 'Save all';
            app.PhaseOme = uiradiobutton(app.OutputPanel);
            app.PhaseOme.Tag = 'PhaseOme';
            app.PhaseOme.Text = 'Save single phase';
            app.PhaseOme.Value = true;
            app.MultiTiff = uiradiobutton(app.OutputPanel);
            app.MultiTiff.Tag = 'MultiTiff';
            app.MultiTiff.Text = 'Save as individual tiffs';
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Folder';
            app.OutputName = uieditfield(app.OutputPanel, 'text');
            app.OutputName.Value = 'CaSignalVsTime';
            app.Session = uiradiobutton(app.OutputPanel);
            app.Session.Tag = 'Session';
            app.Session.Text = 'Save session settings';
            app.EnhancedPhaseMatchingPanel = uibuttongroup(app.UIFigure);
            app.EnhancedPhaseMatchingPanel.Title = 'Plot Graphs';
            app.RunROIMatch = uibutton(app.EnhancedPhaseMatchingPanel, 'push');
            app.RunROIMatch.ButtonPushedFcn = createCallbackFcn(app, @RunROIGoodPeaksPushed, true);
            app.RunROIMatch.Text = 'Plot Raw Data';
            app.SamplingRateLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.CheckNeighbours = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.CheckNeighbours.Limits = [1 Inf];
            app.CheckNeighbours.Value = 2;
            app.Padding = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.Padding.Limits = [0 Inf];
            app.Padding.RoundFractionalValues = 'on';
            app.Padding.Value = 3;
            app.MeanDistLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.MeanDistLabel.Text = 'Mean Dist';
            app.RunROIMatch_2 = uibutton(app.EnhancedPhaseMatchingPanel, 'push');
            app.RunROIMatch_2.Text = 'Plot Normalised Calcium Level';
            app.RunROIMatch_3 = uibutton(app.EnhancedPhaseMatchingPanel, 'push');
            app.RunROIMatch_3.Text = 'Make Graph Movie';
            app.SignalAnalysisPanel = uipanel(app.UIFigure);
            app.SignalAnalysisPanel.Title = 'Signal Analysis';
            app.StartFrameLabel = uilabel(app.SignalAnalysisPanel);
            app.StartFrameLabel.Text = 'Start Frame';
            app.EndFrameLabel = uilabel(app.SignalAnalysisPanel);
            app.EndFrameLabel.Text = 'End Frame';
            app.EndFrame = uieditfield(app.SignalAnalysisPanel, 'numeric');
            app.StartFrame = uieditfield(app.SignalAnalysisPanel, 'numeric');
            app.StartFrame.Value = 1;
            app.Overlay = uibutton(app.SignalAnalysisPanel, 'push');
            app.Overlay.ButtonPushedFcn = createCallbackFcn(app, @OverlayPushed, true);
            app.Overlay.Text = 'Overlay';
            app.SelectROI = uibutton(app.SignalAnalysisPanel, 'push');
            app.SelectROI.Text = 'Select Region of Interest';
            app.GetSignal = uibutton(app.SignalAnalysisPanel, 'push');
            app.GetSignal.Text = 'Get Signal vs Time';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ca Ch';
            app.SetButton = uibutton(app.InputPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Nuc Ch';
            app.BrowseCh2 = uibutton(app.InputPanel, 'push');
            app.BrowseCh2.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh2ButtonPushed, true);
            app.BrowseCh2.Text = 'Browse';
            app.FilenameCh2 = uieditfield(app.InputPanel, 'text');
            app.BrowseCh1 = uibutton(app.InputPanel, 'push');
            app.BrowseCh1.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh1ButtonPushed, true);
            app.BrowseCh1.Text = 'Browse';
            app.FilenameCh1 = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalysis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end